classdef simVaxCEA_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HHCoM                          matlab.ui.Figure
        EndYearEditFieldLabel          matlab.ui.control.Label
        EndYearEditField               matlab.ui.control.NumericEditField
        LampLabel                      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        VaccineCoverageEditFieldLabel  matlab.ui.control.Label
        VaccineCoverageEditField       matlab.ui.control.EditField
        VaccineEfficacyEditFieldLabel  matlab.ui.control.Label
        VaccineEfficacyEditField       matlab.ui.control.EditField
        VaccinateAgeGroupsEditFieldLabel  matlab.ui.control.Label
        VaccinateAgeGroupsEditField    matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        OriginalEfficacyDurationYearsEditFieldLabel  matlab.ui.control.Label
        OriginalEfficacyDurationYearsEditField  matlab.ui.control.EditField
        WaningScenariosLabel           matlab.ui.control.Label
        VaccinationScenariosLabel      matlab.ui.control.Label
        WaningPeriodYearsEditFieldLabel  matlab.ui.control.Label
        WaningPeriodYearsEditField     matlab.ui.control.NumericEditField
        SimulationTimeframeLabel       matlab.ui.control.Label
        WaningVaccineEfficacySwitchLabel  matlab.ui.control.Label
        WaningVaccineEfficacySwitch    matlab.ui.control.Switch
        UIAxes                         matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % inputs for model
            endYear = app.EndYearEditField.Value;
            vaxCover = app.VaccineCoverageEditField.Value;
            vaxEff = app.VaccineEfficacyEditField.Value;
            vaxAge = app.VaccinateAgeGroupsEditField.Value;
            origEffVec = app.OriginalEfficacyDurationYearsEditField.Value;
            t_wane = app.WaningPeriodYearsEditField.Value;
            
            % convert string to double
            vaxCover = str2double(strsplit(vaxCover,',')) / 100;
            vaxEff = str2double(strsplit(vaxEff,',')) / 100;
            vaxAge = str2double(strsplit(vaxAge,','));
            origEffVec = str2double(strsplit(origEffVec , ','));            
            
            waning = strcmp(app.WaningVaccineEfficacySwitch.Value , 'On');
            app.Lamp.Color = [0 1 0];
            simVaxCEA2(endYear, vaxCover , vaxEff, vaxAge ,...
                waning , origEffVec , t_wane)
            app.Lamp.Color = [1 0 0];
            
        end

        % Value changed function: WaningVaccineEfficacySwitch
        function WaningVaccineEfficacySwitchValueChanged(app, event)
            value = app.WaningVaccineEfficacySwitch.Value;
            if strcmp(value , 'On')
                % inputs for model
                endYear = app.EndYearEditField.Value;
                vaxCover = app.VaccineCoverageEditField.Value;
                vaxEff = app.VaccineEfficacyEditField.Value;
                vaxAge = app.VaccinateAgeGroupsEditField.Value;
                origEffVec = app.OriginalEfficacyDurationYearsEditField.Value;
                t_wane = app.WaningPeriodYearsEditField.Value;
                
                % convert string to double
                vaxCover = str2double(strsplit(vaxCover,',')) / 100;
                vaxEff = str2double(strsplit(vaxEff,',')) / 100;
                vaxAge = str2double(strsplit(vaxAge,','));
                origEffVec = str2double(strsplit(origEffVec , ','));
                
                lambdaMultVaxMat = zeros(16 , length(origEffVec));
                lambdaMultVaxMat(vaxAge : 16 , :) =  vaxEff;
                
                waneRate = vaxEff / t_wane;
                
                for i = 1 : length(origEffVec) 
                    lambdaMultVaxMat(round(origEffVec(i) / 5) + vaxAge : 16 , i) = ...
                    linspace(vaxEff , ...
                    vaxEff -vaxEff / t_wane * (16 - (vaxAge + origEffVec(i))) ,...
                    (16 - (vaxAge + origEffVec(i))))';
                end
                for i = 1 : size(lambdaMultVaxMat , 2)
                    hold(app.UIAxes);
                    plot(app.UIAxes , 1 : size(lambdaMultVaxMat , 1) , lambdaMultVaxMat(: , i))
                    hold(app.UIAxes);
                    %                     legend(app.UIAxes , num2str(origEffVec(i)))
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HHCoM = uifigure;
            app.HHCoM.Name = 'UI Figure';
            app.EndYearEditFieldLabel = uilabel(app.HHCoM);
            app.EndYearEditFieldLabel.Text = 'End Year';
            app.EndYearEditField = uieditfield(app.HHCoM, 'numeric');
            app.EndYearEditField.Limits = [2018 2200];
            app.EndYearEditField.Value = 2080;
            app.LampLabel = uilabel(app.HHCoM);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.HHCoM);
            app.VaccineCoverageEditFieldLabel = uilabel(app.HHCoM);
            app.VaccineCoverageEditFieldLabel.Text = 'Vaccine Coverage';
            app.VaccineCoverageEditField = uieditfield(app.HHCoM, 'text');
            app.VaccineCoverageEditField.Value = '90,70,50';
            app.VaccineEfficacyEditFieldLabel = uilabel(app.HHCoM);
            app.VaccineEfficacyEditFieldLabel.Text = 'Vaccine Efficacy';
            app.VaccineEfficacyEditField = uieditfield(app.HHCoM, 'text');
            app.VaccineEfficacyEditField.Value = '63,70,90';
            app.VaccinateAgeGroupsEditFieldLabel = uilabel(app.HHCoM);
            app.VaccinateAgeGroupsEditFieldLabel.Text = 'Vaccinate Age Group(s)';
            app.VaccinateAgeGroupsEditField = uieditfield(app.HHCoM, 'text');
            app.VaccinateAgeGroupsEditField.Value = '3';
            app.RunButton = uibutton(app.HHCoM, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OriginalEfficacyDurationYearsEditFieldLabel = uilabel(app.HHCoM);
            app.OriginalEfficacyDurationYearsEditFieldLabel.Text = 'Original Efficacy Duration (Years)';
            app.OriginalEfficacyDurationYearsEditField = uieditfield(app.HHCoM, 'text');
            app.OriginalEfficacyDurationYearsEditField.Value = '10,15,20';
            app.WaningScenariosLabel = uilabel(app.HHCoM);
            app.WaningScenariosLabel.Text = 'Waning Scenarios';
            app.VaccinationScenariosLabel = uilabel(app.HHCoM);
            app.VaccinationScenariosLabel.Text = 'Vaccination Scenarios';
            app.WaningPeriodYearsEditFieldLabel = uilabel(app.HHCoM);
            app.WaningPeriodYearsEditFieldLabel.Text = 'Waning Period (Years)';
            app.WaningPeriodYearsEditField = uieditfield(app.HHCoM, 'numeric');
            app.WaningPeriodYearsEditField.Limits = [5 200];
            app.WaningPeriodYearsEditField.Value = 20;
            app.SimulationTimeframeLabel = uilabel(app.HHCoM);
            app.SimulationTimeframeLabel.Text = 'Simulation Timeframe';
            app.WaningVaccineEfficacySwitchLabel = uilabel(app.HHCoM);
            app.WaningVaccineEfficacySwitchLabel.Text = 'Waning Vaccine Efficacy';
            app.WaningVaccineEfficacySwitch = uiswitch(app.HHCoM, 'slider');
            app.WaningVaccineEfficacySwitch.ValueChangedFcn = createCallbackFcn(app, @WaningVaccineEfficacySwitchValueChanged, true);
            app.UIAxes = uiaxes(app.HHCoM);
            title(app.UIAxes, 'Vaccine Efficacy')
            xlabel(app.UIAxes, 'Age Group')
            ylabel(app.UIAxes, 'Vaccine Efficacy')
        end
    end

    methods (Access = public)

        % Construct app
        function app = simVaxCEA_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HHCoM)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HHCoM)
        end
    end
end